classdef textApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        TuneTab                      matlab.ui.container.Tab
        PreviewButton                matlab.ui.control.Button
        TuneSlider                   matlab.ui.control.Slider
        TuneSliderLabel              matlab.ui.control.Label
        UIAxes3                      matlab.ui.control.UIAxes
        ExtractTab                   matlab.ui.container.Tab
        ExtractButton                matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        SearchTab                    matlab.ui.container.Tab
        SearchButton                 matlab.ui.control.Button
        EnterWordEditField           matlab.ui.control.EditField
        EnterWordEditFieldLabel      matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        PerfomanceTab                matlab.ui.container.Tab
        RecallEditField              matlab.ui.control.NumericEditField
        RecallEditFieldLabel         matlab.ui.control.Label
        PrecisionEditField           matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel      matlab.ui.control.Label
        ConfusionMatricPanel         matlab.ui.container.Panel
        FalseNegativeEditField       matlab.ui.control.NumericEditField
        FalseNegativeEditFieldLabel  matlab.ui.control.Label
        FalsePositiveEditField       matlab.ui.control.NumericEditField
        FalsePositiveEditFieldLabel  matlab.ui.control.Label
        TrueNegativeEditField        matlab.ui.control.NumericEditField
        TrueNegativeEditFieldLabel   matlab.ui.control.Label
        TruePositiveEditField        matlab.ui.control.NumericEditField
        TruePositiveEditFieldLabel   matlab.ui.control.Label
        AccuracyEditFieldLabel       matlab.ui.control.Label
        AccuracyEditField            matlab.ui.control.NumericEditField
        LoadButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    methods (Access = public)
        function results = func(app, loadImage)
            global structuringElement;
            bnwImage = rgb2gray(loadImage);
            binImage = imbinarize(bnwImage);
            binImage = ~binImage;
            morphElement = strel("disk", structuringElement);
            closedImage = imclose(binImage, morphElement);
            ocrOutput = ocr(closedImage);
            results = ocrOutput;            
        end
    end
    
    methods (Access = private)
        
        function results = reload(app, oImage)
            % Function that performs morphological operation of closing
            global structuringElement;
            bnwImage = rgb2gray(oImage);
            binImage = imbinarize(bnwImage);
            binImage = ~binImage;
            structElem = strel("disk", structuringElement);
            results = imclose(binImage, structElem);
        end
        
        function results = confMat(app, ocrOutput)
            wordConfidences = ocrOutput.WordConfidences;
            tp = 0;
            fp = 0;
            tn = 0;
            fn = 0;
            for conf = wordConfidences
                if conf > 0.6
                    tp = tp + 1;
                else
                    fp = fp + 1;
                end
            end
            accuracyVal = tp / (tp + fp);
            precisionVal = tp / (tp + fp);
            recallVal = 1;
            results = [tp, fp, accuracyVal, precisionVal, recallVal];
            
        end
        
        function results = setPerformance(app, confValues)
            app.TruePositiveEditField.Value = confValues(1, 1);
            app.FalsePositiveEditField.Value = confValues(1, 2);
            %app.AccuracyEditField.Value = confValues(1, 3);
            app.PrecisionEditField.Value = confValues(1, 4);
            app.RecallEditField.Value = confValues(1, 5);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global oImage;
            global structuringElement;
            structuringElement = round(app.TuneSlider.Value);
            [filename, pathname] = uigetfile('*.*', 'Select Image to Extract From');
            if isequal(filename,0) || isequal(pathname,0)
               %disp('User pressed cancel')
               return
            else
               filename = strcat(pathname, filename);
               oImage = imread(filename);
               imshow(oImage, 'Parent',app.UIAxes)
            end
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            global oImage;
            global ocrOutput;
%             j = oImage;
%             x = rgb2gray(j);
%             x = imbinarize(x);
%             x = ~x;
%             g=strel('disk',5);
%             x = imclose(x, g);
%             ocrOutput = ocr(x);
            ocrOutput = func(app, oImage);
            outputText = app.TextArea;
            if isequal(ocrOutput.Text, "")
                outputText.Value = "No Text was Reconized!!!";
            else
                outputText.Value = ocrOutput.Text;
                app.AccuracyEditField.Value = double(mean(ocrOutput.WordConfidences));
                confValues = confMat(app, ocrOutput);
                setPerformance(app, confValues);
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            global oImage;
            %searchText = get(app.EnterWordEditField, "string");
            searchText = app.EnterWordEditField.Value;
            % Perform OCR on image
            ocrReturn = func(app, oImage);
            % Locate text inside image
            textBoundingBox = locateText(ocrReturn, searchText, "IgnoreCase",true);
            Iocr = insertShape(oImage, "FilledRectangle", textBoundingBox);
            imshow(Iocr, 'Parent', app.UIAxes2);
            
        end

        % Value changed function: TuneSlider
        function TuneSliderValueChanged(app, event)
            global structuringElement;
            value = app.TuneSlider.Value;
            structuringElement = round(value);            
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            global oImage;
            binImage = reload(app, oImage);
            imshow(binImage, 'Parent', app.UIAxes3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TuneTab = uitab(app.TabGroup);
            app.TuneTab.Title = 'Tune';
            app.UIAxes3 = uiaxes(app.TuneTab);
            title(app.UIAxes3, 'Output Image')
            app.TuneSliderLabel = uilabel(app.TuneTab);
            app.TuneSliderLabel.Text = 'Tune';
            app.TuneSlider = uislider(app.TuneTab);
            app.TuneSlider.Limits = [0 20];
            app.TuneSlider.MajorTicks = [0 4 8 12 16 20];
            app.TuneSlider.MajorTickLabels = {'0', '4', '8', '12', '16', '20'};
            app.TuneSlider.ValueChangedFcn = createCallbackFcn(app, @TuneSliderValueChanged, true);
            app.TuneSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.TuneSlider.Value = 5;
            app.PreviewButton = uibutton(app.TuneTab, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.ExtractTab = uitab(app.TabGroup);
            app.ExtractTab.Title = 'Extract';
            app.TextArea = uitextarea(app.ExtractTab);
            app.TextArea.Editable = 'off';
            app.ExtractButton = uibutton(app.ExtractTab, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.UIAxes2 = uiaxes(app.SearchTab);
            title(app.UIAxes2, 'Output Image')
            app.EnterWordEditFieldLabel = uilabel(app.SearchTab);
            app.EnterWordEditFieldLabel.Text = 'Enter Word';
            app.EnterWordEditField = uieditfield(app.SearchTab, 'text');
            app.SearchButton = uibutton(app.SearchTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.PerfomanceTab = uitab(app.TabGroup);
            app.PerfomanceTab.Title = 'Perfomance';
            app.AccuracyEditField = uieditfield(app.PerfomanceTab, 'numeric');
            app.AccuracyEditField.Limits = [0 Inf];
            app.AccuracyEditField.RoundFractionalValues = 'on';
            app.AccuracyEditField.Editable = 'off';
            app.AccuracyEditFieldLabel = uilabel(app.PerfomanceTab);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.ConfusionMatricPanel = uipanel(app.PerfomanceTab);
            app.ConfusionMatricPanel.Title = 'Confusion Matric';
            app.TruePositiveEditFieldLabel = uilabel(app.ConfusionMatricPanel);
            app.TruePositiveEditFieldLabel.Text = 'True Positive';
            app.TruePositiveEditField = uieditfield(app.ConfusionMatricPanel, 'numeric');
            app.TruePositiveEditField.Limits = [0 Inf];
            app.TruePositiveEditField.Editable = 'off';
            app.TrueNegativeEditFieldLabel = uilabel(app.ConfusionMatricPanel);
            app.TrueNegativeEditFieldLabel.Text = 'True Negative';
            app.TrueNegativeEditField = uieditfield(app.ConfusionMatricPanel, 'numeric');
            app.TrueNegativeEditField.Limits = [0 Inf];
            app.TrueNegativeEditField.Editable = 'off';
            app.FalsePositiveEditFieldLabel = uilabel(app.ConfusionMatricPanel);
            app.FalsePositiveEditFieldLabel.Text = 'False Positive';
            app.FalsePositiveEditField = uieditfield(app.ConfusionMatricPanel, 'numeric');
            app.FalsePositiveEditField.Limits = [0 Inf];
            app.FalsePositiveEditField.Editable = 'off';
            app.FalseNegativeEditFieldLabel = uilabel(app.ConfusionMatricPanel);
            app.FalseNegativeEditFieldLabel.Text = 'False Negative';
            app.FalseNegativeEditField = uieditfield(app.ConfusionMatricPanel, 'numeric');
            app.FalseNegativeEditField.Limits = [0 Inf];
            app.FalseNegativeEditField.Editable = 'off';
            app.PrecisionEditFieldLabel = uilabel(app.PerfomanceTab);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.PerfomanceTab, 'numeric');
            app.PrecisionEditField.Limits = [0 Inf];
            app.PrecisionEditField.RoundFractionalValues = 'on';
            app.PrecisionEditField.Editable = 'off';
            app.RecallEditFieldLabel = uilabel(app.PerfomanceTab);
            app.RecallEditFieldLabel.Text = 'Recall';
            app.RecallEditField = uieditfield(app.PerfomanceTab, 'numeric');
            app.RecallEditField.Limits = [0 Inf];
            app.RecallEditField.RoundFractionalValues = 'on';
            app.RecallEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = textApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end